classdef Maliyet_Hesap_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UYGULAMASILabel                matlab.ui.control.Label
        Image6                         matlab.ui.control.Image
        TRAFKKAZAMALYETHESAPLAMALabel  matlab.ui.control.Label
        Image5                         matlab.ui.control.Image
        Image2_4                       matlab.ui.control.Image
        Image2_3                       matlab.ui.control.Image
        Image2_2                       matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        Sonuc                          matlab.ui.control.Label
        HesaplananMaliyetLabel         matlab.ui.control.Label
        BusinessNumber                 matlab.ui.control.EditField
        AraveletmeSaysEditFieldLabel   matlab.ui.control.Label
        CarNumber                      matlab.ui.control.EditField
        TatSaysLabel                   matlab.ui.control.Label
        WoundedNumber                  matlab.ui.control.EditField
        YaralSaysEditFieldLabel        matlab.ui.control.Label
        DeadNumber                     matlab.ui.control.EditField
        lSaysLabel                     matlab.ui.control.Label
        MaliyetiHesaplaButton          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MaliyetiHesaplaButton
        function MaliyetiHesaplaButtonPushed(app, event)
        % Edittextlerden parametreleri alın
        deadNumber = str2double(app.DeadNumber.Value);
        woundedNumber = str2double(app.WoundedNumber.Value);
        carNumber = str2double(app.CarNumber.Value);
        businessNumber = str2double(app.BusinessNumber.Value);

        % Eksik parametre kontrolü yapın
        if isnan(deadNumber) || isnan(woundedNumber) || isnan(carNumber) || isnan(businessNumber)
        % Eğer herhangi bir parametre eksikse uyarı ver
        uialert(app.UIFigure, 'Lütfen bütün değerleri doldurunuz.', 'Eksik Parametre', 'Icon', 'warning');
        else
         app.Image.Visible = 'on';
         app.Image6.Visible = 'on';
         app.Sonuc.Visible = 'off';
        % Eğer bütün parametreler mevcutsa modeli yükle ve tahmin yap
        load('C:\Users\emrea\OneDrive\Desktop\tez\linearModel.mat');
        features = [deadNumber, woundedNumber, carNumber, businessNumber];
        prediction = predict(model, features);
        % Tahmini Sonucu göster
        % Sayıyı milyon, milyar gibi büyük sayı formatına dönüştür
        formattedPrediction = sprintf('%1.0f', prediction); % Sayıyı formatla (ondalık kısmı at)
        formattedPredictionWithDots = regexprep(formattedPrediction, '(\d)(?=(\d{3})+$)', '$1.'); % Noktaları ekle
             pause(3);
        app.Image6.Visible = 'off';
        app.Sonuc.Visible = 'on';
        app.Sonuc.Text = [formattedPredictionWithDots, ' $'];
        app.Image.Visible = 'off';
        end
        end

        % Value changed function: DeadNumber
        function DeadNumberValueChanged(app, event)
            value = app.DeadNumber.Value;
           
    if ~isempty(value)
        app.Image2.Visible = 'on';
    else
      
        app.Image2.Visible = 'off';
    end
            
        end

        % Value changed function: WoundedNumber
        function WoundedNumberValueChanged(app, event)
            value = app.WoundedNumber.Value;
             if ~isempty(value)
        app.Image2_2.Visible = 'on';
    else
      
        app.Image2_2.Visible = 'off';
    end
        end

        % Value changed function: CarNumber
        function CarNumberValueChanged(app, event)
            value = app.CarNumber.Value;
             if ~isempty(value)
        app.Image2_3.Visible = 'on';
    else
      
        app.Image2_3.Visible = 'off';
    end
        end

        % Value changed function: BusinessNumber
        function BusinessNumberValueChanged(app, event)
            value = app.BusinessNumber.Value;
             if ~isempty(value)
        app.Image2_4.Visible = 'on';
    else
      
        app.Image2_4.Visible = 'off';
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MaliyetiHesaplaButton = uibutton(app.UIFigure, 'push');
            app.MaliyetiHesaplaButton.ButtonPushedFcn = createCallbackFcn(app, @MaliyetiHesaplaButtonPushed, true);
            app.MaliyetiHesaplaButton.Text = 'Maliyeti Hesapla';
            app.lSaysLabel = uilabel(app.UIFigure);
            app.lSaysLabel.Text = 'Ölü Sayısı :';
            app.DeadNumber = uieditfield(app.UIFigure, 'text');
            app.DeadNumber.InputType = 'digits';
            app.DeadNumber.ValueChangedFcn = createCallbackFcn(app, @DeadNumberValueChanged, true);
            app.YaralSaysEditFieldLabel = uilabel(app.UIFigure);
            app.YaralSaysEditFieldLabel.Text = 'Yaralı Sayısı :';
            app.WoundedNumber = uieditfield(app.UIFigure, 'text');
            app.WoundedNumber.InputType = 'digits';
            app.WoundedNumber.ValueChangedFcn = createCallbackFcn(app, @WoundedNumberValueChanged, true);
            app.TatSaysLabel = uilabel(app.UIFigure);
            app.TatSaysLabel.Text = 'Taşıt Sayısı : ';
            app.CarNumber = uieditfield(app.UIFigure, 'text');
            app.CarNumber.InputType = 'digits';
            app.CarNumber.ValueChangedFcn = createCallbackFcn(app, @CarNumberValueChanged, true);
            app.AraveletmeSaysEditFieldLabel = uilabel(app.UIFigure);
            app.AraveletmeSaysEditFieldLabel.Text = 'Araç ve İşletme Sayısı :';
            app.BusinessNumber = uieditfield(app.UIFigure, 'text');
            app.BusinessNumber.InputType = 'digits';
            app.BusinessNumber.ValueChangedFcn = createCallbackFcn(app, @BusinessNumberValueChanged, true);
            app.HesaplananMaliyetLabel = uilabel(app.UIFigure);
            app.HesaplananMaliyetLabel.Text = 'Hesaplanan Maliyet :';
            app.Sonuc = uilabel(app.UIFigure);
            app.Sonuc.Text = 'Lütfen Parametreleri Giriniz.';
            app.Image = uiimage(app.UIFigure);
            app.Image.Visible = 'off';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'ANALİZGİFT.gif');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.Visible = 'off';
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'okey.gif');
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.Visible = 'off';
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'okey.gif');
            app.Image2_3 = uiimage(app.UIFigure);
            app.Image2_3.Visible = 'off';
            app.Image2_3.ImageSource = fullfile(pathToMLAPP, 'okey.gif');
            app.Image2_4 = uiimage(app.UIFigure);
            app.Image2_4.Visible = 'off';
            app.Image2_4.ImageSource = fullfile(pathToMLAPP, 'okey.gif');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'PngItem_4758155.png');
            app.TRAFKKAZAMALYETHESAPLAMALabel = uilabel(app.UIFigure);
            app.TRAFKKAZAMALYETHESAPLAMALabel.Text = 'TRAFİK KAZA MALİYETİ HESAPLAMA ';
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.Visible = 'off';
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'spinner.gif');
            app.UYGULAMASILabel = uilabel(app.UIFigure);
            app.UYGULAMASILabel.Text = 'UYGULAMASI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Maliyet_Hesap_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end